classdef ControlSystem1FSR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        ResetButton  matlab.ui.control.Button
        ForceSensingResistorRealTimeDataLabel  matlab.ui.control.Label
        StartButton  matlab.ui.control.Button
        axes1        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             % Arduino setup function
            global a;
            a = arduino('COM3','Uno')
            plot(app.axes1,0);
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            x=0;
            v=0;

            global a;
            
            %for i=1:1:n , n is number of interval
            tic;
            
         
            for i=1:1:200
                yval = readVoltage(a,'A0');
                toc;
                v=[v,toc];
                x=[x,yval];
                %%initial code 1%%
%                 plot(app.axes1,x);
                %%%%
               
                
                %%%animatedline test%%%
                h= animatedline(app.axes1);
                addpoints(h,v,x);
                drawnow;
        
                %%%%%%%
                
                xt = [x];
                xv=[v];
                t=table([xv.'],[xt.']);

                pause(0.1);
                v
                
            end
          pressure = "presdata"+ datestr(now, 'dd-mmm-yyyy HH-MM-SS'),'';
            writetable(t,pressure);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            clear all;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
%            
%             app.EditField.Value ="App has been reset to default";
            plot(app.axes1,0);
           
%             app.MaxPressurembarEditField.Value= 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.axes1 = uiaxes(app.UIFigure);
            xlabel(app.axes1, 'Time (s)')
            ylabel(app.axes1, 'Voltage (V)')
            app.axes1.Tag = 'axes1';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ForceSensingResistorRealTimeDataLabel = uilabel(app.UIFigure);
            app.ForceSensingResistorRealTimeDataLabel.Text = 'Force Sensing Resistor (Real-Time Data)';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ControlSystem1FSR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end