classdef ControlSystem6DoFsteppersTesting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FSRReaderButton            matlab.ui.control.Button
        StartButton_Gait_3         matlab.ui.control.Button
        StartButton_Gait_2         matlab.ui.control.Button
        EditField_inf              matlab.ui.control.NumericEditField
        Label_6                    matlab.ui.control.Label
        EditField_ant              matlab.ui.control.NumericEditField
        Label_5                    matlab.ui.control.Label
        EditField_med              matlab.ui.control.NumericEditField
        Label_4                    matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        EditField_int              matlab.ui.control.NumericEditField
        Label_3                    matlab.ui.control.Label
        EditField_var              matlab.ui.control.NumericEditField
        EditField_flex             matlab.ui.control.NumericEditField
        Label                      matlab.ui.control.Label
        InitializeButton           matlab.ui.control.Button
        TranslationalControlLabel  matlab.ui.control.Label
        StopButton_ant             matlab.ui.control.Button
        StartButton_ant            matlab.ui.control.Button
        StopButton_inf             matlab.ui.control.Button
        StartButton_inf            matlab.ui.control.Button
        StopButton_med             matlab.ui.control.Button
        StartButton_med            matlab.ui.control.Button
        MedioLateralFyLabel        matlab.ui.control.Label
        AnteriorPosteriorFxLabel   matlab.ui.control.Label
        InternalExternalMzLabel    matlab.ui.control.Label
        RotationalControlLabel     matlab.ui.control.Label
        StatusLamp                 matlab.ui.control.Lamp
        StatusLampLabel            matlab.ui.control.Label
        EditStatus                 matlab.ui.control.EditField
        StartButton_Gait           matlab.ui.control.Button
        StopButton_Gait            matlab.ui.control.Button
        MultipleDOFsRoutineLabel   matlab.ui.control.Label
        StopButton_var             matlab.ui.control.Button
        StartButton_var            matlab.ui.control.Button
        StopButton_int             matlab.ui.control.Button
        StartButton_int            matlab.ui.control.Button
        StopButton_flex            matlab.ui.control.Button
        StartButton_flex           matlab.ui.control.Button
        DistractingInterpenetratingFzLabel  matlab.ui.control.Label
        AbductingAdductingMxLabel  matlab.ui.control.Label
        FlexionExtensionMyLabel    matlab.ui.control.Label
        StepperControlSystemLabel  matlab.ui.control.Label
        UIAxes2_3                  matlab.ui.control.UIAxes
        UIAxes2_2                  matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
    end


    properties (Access = private)
    end
    
    properties (Access = public)
        a % arduino variable
        step % step increment
        step1 % updated step values (stepper 1 to 6)
        step2 
        step3
        step4
        step5
        step6

        sm % stepper #1
        sm2 % stepper #2
        sm3 % stepper #3
        sm4 % stepper #4
        sm5 % stepper #5
        sm6 % stepper #6
        angle % angle
        newangle % current angle
        
    end
    
    methods (Access = private)
        
        function results = func(app)
            
        end
        
        function results = func2(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            movegui(app.UIFigure, 'onscreen');

            % Assign variables
            app.step = 100;
            app.step1 = 0;
            app.step2 = 0;
            app.step3 = 0;
            app.step4 = 0;
            app.step5 = 0;
            app.step6 = 0;

            % Arduino connection -change COM port, Arduino model if necessary
            disp('...Checking Arduino connection to MATLAB ...')
            app.EditStatus.Value = "Checking Arduino connection to MATLAB";
            app.StatusLamp.Color = 'r';
            app.a = arduino('COM4','Uno','Libraries','Adafruit\MotorShieldV2');
            disp(app.a);
            % arduinoObj = serialport('COM3',9600)
            addrs = scanI2CBus(app.a,0);
            pause(1)

            % test pin connection -- test simple led circuit for pin D9
            disp('...Checking digital pins connection...')
            pause(2)
            for i=1:1:5
                writeDigitalPin(app.a ,'D9', 1)
                writeDigitalPin(app.a ,'D9', 0)
            end
            pause(2)
            disp('Pin connection test successful (digital pin 9 is tested)')
            pause(2)
            disp('                    ')

            % Check if MATLAB could detect all three motorshields
            i = 0;
            if i == 0
                disp('...Checking Adafruit Motorshield connection...');
                pause(1);
                if isequal(addrs{1},'0x60')
                    ms1 = 1;
                    app.EditStatus.Value = 'Motorshield 1 is detected';
                    disp ('Motorshield 1 is detected');
                    pause(1)
                else
                    ms1 = 0;
                    app.EditStatus.Value = 'Motorshield 1 is not detected';
                    disp('Motorshield 1 is not detected');
                    pause(1)
                end

                if isequal(addrs{2},'0x61')
                    ms2 = 1;
                    app.EditStatus.Value = 'Motorshield 2 is detected';
                    disp ('Motorshield 2 is detected');
                    pause(1)
                else
                    ms2 = 0;
                    app.EditStatus.Value = 'Motorshield 2 is not detected';
                    disp('Motorshield 2 is not detected');
                    pause(1)
                end

                if isequal(addrs{3},'0x62')
                    ms3 = 1;
                    app.EditStatus.Value = 'Motorshield 3 is detected';
                    disp ('Motorshield 3 is detected');
                    pause(1)
                else
                    ms3 = 0;
                    app.EditStatus.Value = 'Motorshield 3 is not detected';
                    disp('Motorshield 3 is not detected');
                    pause(1)
                end
            end

            pause(1)
            % ...Shields addresses and properties...

            shield1 = addon(app.a,'Adafruit\MotorshieldV2','I2CAddress',0x60);
            shield2 = addon(app.a,'Adafruit\MotorshieldV2','I2CAddress',0x61);
            shield3 = addon(app.a,'Adafruit\MotorshieldV2','I2CAddress',0x62);
            disp('                    ');
            disp('...Assigning addresses for each shields...');
            pause(2);
            disp('shield1');
            disp(shield1.I2CAddress);
            disp('shield2');
            disp(shield2.I2CAddress);
            disp('shield3');
            disp(shield3.I2CAddress);

            if ms1 == ms2 == ms3 == 1
                disp('...Motorshield Setup is done and ready to use...');
            
                app.EditStatus.Value = "Motorshield initialization is complete";
                app.StatusLamp.Color = 'g';
                pause(2);
                app.EditStatus.Value = "App is ready to use";
            else
                disp('...Motorshield Setup failed, try checking motorshield connection...');
                app.EditStatus.Value = "Motorshield initialization failed, check connection";
            end         


            % Assign each stepper motor to a variable (add 4 more steppers)
           
            app.sm = stepper(shield3,1,48,'RPM',200,'StepType','Single');


            app.sm2 = stepper(shield3,2,200,'RPM',200,'StepType','Microstep');


            app.sm3 = stepper(shield2,1,200,'RPM',200,'StepType','Single');


            app.sm4 = stepper(shield2,2,200,'RPM',200,'StepType','Microstep');


            app.sm5 = stepper(shield1,1,200,'RPM',200,'StepType','Single');


            app.sm6 = stepper(shield1,2,200,'RPM',200,'StepType','Single');


            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to steppercontrol (see VARARGIN)
            
            % Choose default command line output for steppercontrol
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: StartButton_flex
        function StartButton_flexPushed(app, event)
              % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
        
            app.a;

%             move(app.sm, -100);
            disp("Stepper 1 forward")
          
%             for i=1:1:5
%                 writeDigitalPin(app.a ,'D9', 1)
%                 writeDigitalPin(app.a ,'D9', 0)
%             end
%             
            if app.step1 < 2100
                app.step1 = app.step1 + 300;
                disp (app.step1);

                app.EditField_flex.Value =app.step1;

                move(app.sm, 300);
            end
            
        end

        % Button pushed function: StopButton_flex
        function StopButton_flexPushed(app, event)
                % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
           
            app.a;
% 
%             move(app.sm, 100);
            disp("Stepper 1 backward")
         
            if app.step1 > -2100
                app.step1 = app.step1 - 300;
                disp (app.step1);
                app.EditField_flex.Value =app.step1;

              
                move(app.sm, -300);
            end
        end

        % Button pushed function: StartButton_int
        function StartButton_intPushed(app, event)
                % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            app.a;

%             move(app.sm3, -500);
            disp("Stepper 3 forward")
          
            if app.step3 < 2100
                app.step3 = app.step3 + app.step;
                disp (app.step3);
                app.EditField_int.Value =app.step3;

              
                move(app.sm3, 500);
            end
        end

        % Button pushed function: StopButton_int
        function StopButton_intPushed(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            app.a;

% 
%             move(app.sm3, 500);
            disp("Stepper 3 backward")
            if app.step3 > -2100
                app.step3 = app.step3 - app.step;
                disp (app.step3);
                app.EditField_int.Value =app.step3;


                move(app.sm3, -500);
            end
        end

        % Button pushed function: StartButton_var
        function StartButton_varPushed(app, event)
                 % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            app.a;
%             move(app.sm2, -100);
            disp("Stepper 2 forward")
            if app.step2 < 2100
                app.step2 = app.step2 + app.step;
                disp (app.step2);
                app.EditField_var.Value =app.step2;


                move(app.sm2, app.step);
            end

        end

        % Button pushed function: StopButton_var
        function StopButton_varPushed(app, event)
               % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
           
            app.a;
%             move(app.sm2, 100);
            disp("Stepper 2 backward")
        
            if app.step2 > -2100
                app.step2 = app.step2 - app.step;
                disp (app.step2);
                app.EditField_var.Value =app.step2;


                move(app.sm2, -app.step);
            end
        end

        % Button pushed function: StartButton_Gait
        function StartButton_GaitPushed(app, event)
              % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            app.a;
% 
%             move(app.sm, 1);
%             move(app.sm2, 1);
%             move(app.sm3, 1);
%             move(app.sm4, 1);
%             move(app.sm5, 1);
%             move(app.sm6, 1);
%    -------------------------------------------------------------        
% %             % Move both steppers
%             for i=1:1:10
%                 move(app.sm3, 100);
%                 move(app.sm3, -100);
%             end
% ---------------------------------------------------

  app.EditStatus.Value = "Testing loop for Mx and Mz ";

            for i=1:1:100
                move(app.sm3, 10);
                move(app.sm4, 1);
                move(app.sm2, 1);
            end
            %
%             for i=1:1:10
%                 move(app.sm3, 1);
%                 move(app.sm3, -100);
%             end


%             move(app.sm, -20);
%             wait(10);
%             move(app.sm2, -20);
%             wait(10);
%             move(app.sm3, -20);
%             wait(10);
%             move(app.sm4, -20);
%             wait(10);
%             move({app.sm5, app.sm6}, -100);

%             move(app.sm6, -100);


            disp("test all steppers done")

        end

        % Button pushed function: StopButton_Gait
        function StopButton_GaitPushed(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            app.a;
% 
            release(app.sm);
            release(app.sm2);
            release(app.sm3);
            release(app.sm4);
            release(app.sm5);
            release(app.sm6);

            disp("release all steppers done")
        end

        % Button pushed function: StartButton_med
        function StartButton_medPushed(app, event)
                 % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            app.a;

%             move(app.sm4, 100);
            disp("Stepper 4 forward")
          
            if app.step4 < 2100
                app.step4 = app.step4 + app.step;
                disp (app.step4);
                app.EditField_med.Value =app.step4;


                move(app.sm4, app.step);
            end
       
        end

        % Button pushed function: StopButton_med
        function StopButton_medPushed(app, event)
               % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
           
            app.a;
% 
%             move(app.sm4, -100);
            disp("Stepper 4 backward")
            %
            if app.step4 > -2100
                app.step4 = app.step4 - app.step;
                disp (app.step4);
                app.EditField_med.Value =app.step4;


                move(app.sm4, -app.step);
            end
      
        end

        % Button pushed function: StartButton_inf
        function StartButton_infPushed(app, event)
                 % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            app.a;
%             move(app.sm6, -500);
            disp("Stepper 6 forward")

             if app.step6 < 2100
                app.step6 = app.step6 + app.step;
                disp (app.step6);
                app.EditField_inf.Value = app.step6;

                move(app.sm6, 500);
             end 
        end

        % Button pushed function: StopButton_inf
        function StopButton_infPushed(app, event)
               % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
           
            app.a;
%             move(app.sm6, 500);
            disp("Stepper 6 backward")
        
             if app.step6 > -2100
                app.step6 = app.step6 - app.step;
                disp (app.step6);
                app.EditField_inf.Value =app.step6;

                move(app.sm6, -500);
             end 
        end

        % Button pushed function: StartButton_ant
        function StartButton_antPushed(app, event)
                 % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            app.a;
%             move(app.sm5, 1000);
            disp("Stepper 5 forward")
             if app.step5 < 2100
                app.step5 = app.step5 + app.step;
                disp (app.step5);
                app.EditField_ant.Value =app.step5;

                move(app.sm5, 500);
             end 
        end

        % Button pushed function: StopButton_ant
        function StopButton_antPushed(app, event)
               % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
           
            app.a;
        
%             move(app.sm5, -1000);
            disp("Stepper 5 backward")

            if app.step5 > -2100
                app.step5 = app.step5 - app.step;
                disp (app.step5);
                app.EditField_ant.Value =app.step5;

                move(app.sm5, -500);
             end 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            clear all;
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            move(app.sm, 1);
            move(app.sm2, 1);
            move(app.sm3, 1);
            move(app.sm4, 1);
            move(app.sm5, 1);
            move(app.sm6, 1);
            disp("Initialization of stepper motors is complete")

        end

        % Button pushed function: StartButton_Gait_2
        function StartButton_Gait_2Pushed(app, event)
                 % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            app.a;


  app.EditStatus.Value = "Testing loop for x and y ";

            for i=1:1:100
                move(app.sm5, 10);
                move(app.sm6, 10);
               
            end
        end

        % Button pushed function: StartButton_Gait_3
        function StartButton_Gait_3Pushed(app, event)
                    % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            app.a;


  app.EditStatus.Value = "Testing loop for z";

            for i=1:1:10
                move(app.sm3, 100);
                move(app.sm3, -100);
               
            end
        end

        % Button pushed function: FSRReaderButton
        function FSRReaderButtonPushed(app, event)
            ControlSystem1FSR
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XLim = [0 1];
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            app.UIAxes2_2.XLim = [0 1];
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.YTick = [];
            app.UIAxes2_3 = uiaxes(app.UIFigure);
            app.UIAxes2_3.XLim = [0 1];
            app.UIAxes2_3.XTick = [];
            app.UIAxes2_3.YTick = [];
            app.StepperControlSystemLabel = uilabel(app.UIFigure);
            app.StepperControlSystemLabel.Text = 'Stepper Control System ';
            app.FlexionExtensionMyLabel = uilabel(app.UIFigure);
            app.FlexionExtensionMyLabel.Text = 'Flexion-Extension (My)';
            app.AbductingAdductingMxLabel = uilabel(app.UIFigure);
            app.AbductingAdductingMxLabel.Text = 'Abducting-Adducting (Mx)';
            app.DistractingInterpenetratingFzLabel = uilabel(app.UIFigure);
            app.DistractingInterpenetratingFzLabel.Text = 'Distracting-Interpenetrating (Fz)';
            app.StartButton_flex = uibutton(app.UIFigure, 'push');
            app.StartButton_flex.ButtonPushedFcn = createCallbackFcn(app, @StartButton_flexPushed, true);
            app.StartButton_flex.Text = 'Forward';
            app.StopButton_flex = uibutton(app.UIFigure, 'push');
            app.StopButton_flex.ButtonPushedFcn = createCallbackFcn(app, @StopButton_flexPushed, true);
            app.StopButton_flex.Text = 'Backward';
            app.StartButton_int = uibutton(app.UIFigure, 'push');
            app.StartButton_int.ButtonPushedFcn = createCallbackFcn(app, @StartButton_intPushed, true);
            app.StartButton_int.Text = 'Forward';
            app.StopButton_int = uibutton(app.UIFigure, 'push');
            app.StopButton_int.ButtonPushedFcn = createCallbackFcn(app, @StopButton_intPushed, true);
            app.StopButton_int.Text = 'Backward';
            app.StartButton_var = uibutton(app.UIFigure, 'push');
            app.StartButton_var.ButtonPushedFcn = createCallbackFcn(app, @StartButton_varPushed, true);
            app.StartButton_var.Text = 'Forward';
            app.StopButton_var = uibutton(app.UIFigure, 'push');
            app.StopButton_var.ButtonPushedFcn = createCallbackFcn(app, @StopButton_varPushed, true);
            app.StopButton_var.Text = 'Backward';
            app.MultipleDOFsRoutineLabel = uilabel(app.UIFigure);
            app.MultipleDOFsRoutineLabel.Text = 'Multiple DOFs Routine';
            app.StopButton_Gait = uibutton(app.UIFigure, 'push');
            app.StopButton_Gait.ButtonPushedFcn = createCallbackFcn(app, @StopButton_GaitPushed, true);
            app.StopButton_Gait.Text = 'Release';
            app.StartButton_Gait = uibutton(app.UIFigure, 'push');
            app.StartButton_Gait.ButtonPushedFcn = createCallbackFcn(app, @StartButton_GaitPushed, true);
            app.StartButton_Gait.Text = 'Mode 1';
            app.EditStatus = uieditfield(app.UIFigure, 'text');
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            app.RotationalControlLabel = uilabel(app.UIFigure);
            app.RotationalControlLabel.Text = 'Rotational Control';
            app.InternalExternalMzLabel = uilabel(app.UIFigure);
            app.InternalExternalMzLabel.Text = 'Internal-External (Mz)';
            app.AnteriorPosteriorFxLabel = uilabel(app.UIFigure);
            app.AnteriorPosteriorFxLabel.Text = 'Anterior-Posterior (Fx)';
            app.MedioLateralFyLabel = uilabel(app.UIFigure);
            app.MedioLateralFyLabel.Text = 'Medio-Lateral (Fy)';
            app.StartButton_med = uibutton(app.UIFigure, 'push');
            app.StartButton_med.ButtonPushedFcn = createCallbackFcn(app, @StartButton_medPushed, true);
            app.StartButton_med.Text = 'Forward';
            app.StopButton_med = uibutton(app.UIFigure, 'push');
            app.StopButton_med.ButtonPushedFcn = createCallbackFcn(app, @StopButton_medPushed, true);
            app.StopButton_med.Text = 'Backward';
            app.StartButton_inf = uibutton(app.UIFigure, 'push');
            app.StartButton_inf.ButtonPushedFcn = createCallbackFcn(app, @StartButton_infPushed, true);
            app.StartButton_inf.Text = 'Forward';
            app.StopButton_inf = uibutton(app.UIFigure, 'push');
            app.StopButton_inf.ButtonPushedFcn = createCallbackFcn(app, @StopButton_infPushed, true);
            app.StopButton_inf.Text = 'Backward';
            app.StartButton_ant = uibutton(app.UIFigure, 'push');
            app.StartButton_ant.ButtonPushedFcn = createCallbackFcn(app, @StartButton_antPushed, true);
            app.StartButton_ant.Text = 'Forward';
            app.StopButton_ant = uibutton(app.UIFigure, 'push');
            app.StopButton_ant.ButtonPushedFcn = createCallbackFcn(app, @StopButton_antPushed, true);
            app.StopButton_ant.Text = 'Backward';
            app.TranslationalControlLabel = uilabel(app.UIFigure);
            app.TranslationalControlLabel.Text = 'Translational Control';
            app.InitializeButton = uibutton(app.UIFigure, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Position';
            app.EditField_flex = uieditfield(app.UIFigure, 'numeric');
            app.EditField_flex.Editable = 'off';
            app.EditField_var = uieditfield(app.UIFigure, 'numeric');
            app.EditField_var.Editable = 'off';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Position';
            app.EditField_int = uieditfield(app.UIFigure, 'numeric');
            app.EditField_int.Editable = 'off';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Position';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'Position';
            app.EditField_med = uieditfield(app.UIFigure, 'numeric');
            app.EditField_med.Editable = 'off';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'Position';
            app.EditField_ant = uieditfield(app.UIFigure, 'numeric');
            app.EditField_ant.Editable = 'off';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'Position';
            app.EditField_inf = uieditfield(app.UIFigure, 'numeric');
            app.EditField_inf.Editable = 'off';
            app.StartButton_Gait_2 = uibutton(app.UIFigure, 'push');
            app.StartButton_Gait_2.ButtonPushedFcn = createCallbackFcn(app, @StartButton_Gait_2Pushed, true);
            app.StartButton_Gait_2.Text = 'Mode 2';
            app.StartButton_Gait_3 = uibutton(app.UIFigure, 'push');
            app.StartButton_Gait_3.ButtonPushedFcn = createCallbackFcn(app, @StartButton_Gait_3Pushed, true);
            app.StartButton_Gait_3.Text = 'Mode 3';
            app.FSRReaderButton = uibutton(app.UIFigure, 'push');
            app.FSRReaderButton.ButtonPushedFcn = createCallbackFcn(app, @FSRReaderButtonPushed, true);
            app.FSRReaderButton.Text = 'FSR Reader';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ControlSystem6DoFsteppersTesting(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end