classdef ControlSystemMultipleFSRs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        ResetButton  matlab.ui.control.Button
        ForceSensingResistorRealTimeDataLabel  matlab.ui.control.Label
        StartButton  matlab.ui.control.Button
        axes5        matlab.ui.control.UIAxes
        axes4        matlab.ui.control.UIAxes
        axes3        matlab.ui.control.UIAxes
        axes2        matlab.ui.control.UIAxes
        axes1        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        a % Description
        y1 % Description
        y2 % Description
        y3 % Description
        y4 % Description
        ytotal 
        x % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             % Arduino setup function
%             app.a = arduino('COM3','Uno','Libraries','Adafruit\MotorShieldV2');
            app.a = arduino('COM3','Uno')
            app.x = 0;
            app.y1 = 0;
            app.y2 = 0;
            app.y3 = 0;
            app.y4 = 0;
            app.ytotal = 0;
            plot(app.axes1,0);
            plot(app.axes2,0);
            plot(app.axes3,0);
            plot(app.axes4,0);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
          
            
            %for i=1:1:n , n is number of interval
            tic;
            
         
            for i=1:1:100
                yval1 = readVoltage(app.a,'A0');
                yval2 = readVoltage(app.a,'A1');
                yval3 = readVoltage(app.a,'A2');
                yval4 = readVoltage(app.a,'A3');
                toc;
                app.x=[app.x,toc];
                app.y1=[app.y1,yval1];
                app.y2=[app.y2,yval2];
                app.y3=[app.y3,yval3];
                app.y4=[app.y4,yval4];
              
                ytotal= (yval1 + yval2 + yval3 + yval4) / 4
                app.ytotal=[app.ytotal,ytotal]
                
                %%%animatedline test%%%
                h1= animatedline(app.axes1);
                h2= animatedline(app.axes2);
                h3= animatedline(app.axes3);
                h4= animatedline(app.axes4);
                addpoints(h1,app.x,app.y1);
                addpoints(h2,app.x,app.y2);
                addpoints(h3,app.x,app.y3);
                addpoints(h4,app.x,app.y4);

                ht= animatedline(app.axes5);
                addpoints(ht,app.x,app.ytotal);
                drawnow;


                yt = [app.y1];
                xt=[app.x];
                t=table([xt.'],[yt.']);

                pause(0.1);
                
            end
display(yt)
            pressure = "presdata"+ datestr(now, 'dd-mmm-yyyy HH-MM-SS'),'';
            writetable(t,pressure);
        
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            clear all;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
             app.x = 0;
            app.y1 = 0;
            app.y2 = 0;
            app.y3 = 0;
            app.y4 = 0;
            app.ytotal = 0;
            plot(app.axes1,0);
            plot(app.axes2,0);
            plot(app.axes3,0);
            plot(app.axes4,0);
            plot(app.axes5,0);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.axes1 = uiaxes(app.UIFigure);
            app.axes1.Tag = 'axes1';
            app.axes2 = uiaxes(app.UIFigure);
            app.axes2.Tag = 'axes1';
            app.axes3 = uiaxes(app.UIFigure);
            app.axes3.Tag = 'axes1';
            app.axes4 = uiaxes(app.UIFigure);
            app.axes4.Tag = 'axes1';
            app.axes5 = uiaxes(app.UIFigure);
            app.axes5.Tag = 'axes1';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ForceSensingResistorRealTimeDataLabel = uilabel(app.UIFigure);
            app.ForceSensingResistorRealTimeDataLabel.Text = 'Force Sensing Resistor (Real-Time Data)';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ControlSystemMultipleFSRs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end