classdef ControlSystemSingleFSR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        ResetButton  matlab.ui.control.Button
        ForceSensingResistorRealTimeDataLabel  matlab.ui.control.Label
        StartButton  matlab.ui.control.Button
        axes1        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        a % Description
        y1 % Description
        y2 % Description
        y3 % Description
        y4 % Description
        ytotal 
        x % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             % Arduino setup function
            app.a = arduino('COM3','Uno','Libraries','Adafruit\MotorShieldV2');
            app.x = 0;
            app.y1 = 0;
            plot(app.axes1,0);
          
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
          
            
            %for i=1:1:n , n is number of interval
            tic;
            
         
            for i=1:1:100
                yval1 = readVoltage(app.a,'A0');
                
                toc;
                app.x=[app.x,toc];
                app.y1=[app.y1,yval1];


                %%%animatedline test%%%
                h1= animatedline(app.axes1);
                
                addpoints(h1,app.x,app.y1);
                
                drawnow;


                yt = [app.y1];
                xt=[app.x];
                t=table([xt.'],[yt.']);

                pause(0.1);
                
            end
display(yt)
            force = "fsrdata"+ datestr(now, 'dd-mmm-yyyy HH-MM-SS'),'';
            writetable(t,force);
        
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            clear all;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
             app.x = 0;
            app.y1 = 0;
       
            plot(app.axes1,0);
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.axes1 = uiaxes(app.UIFigure);
            app.axes1.Tag = 'axes1';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ForceSensingResistorRealTimeDataLabel = uilabel(app.UIFigure);
            app.ForceSensingResistorRealTimeDataLabel.Text = 'Force Sensing Resistor (Real-Time Data)';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ControlSystemSingleFSR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end